classdef PathPlanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ServerStatusPanel          matlab.ui.container.Panel
        ExportLocationEditField    matlab.ui.control.EditField
        Label_6                    matlab.ui.control.Label
        ExportPathButton           matlab.ui.control.Button
        ClearLogButton             matlab.ui.control.Button
        CommunicationLogTextArea   matlab.ui.control.TextArea
        CommunicationLogLabel      matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
        StatusLampLabel            matlab.ui.control.Label
        DisconnectButton           matlab.ui.control.Button
        ConnectButton              matlab.ui.control.Button
        Panel_2                    matlab.ui.container.Panel
        TrajectoryScope            matlab.ui.container.Panel
        UIAxes1                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        MRExHYPERCUBEConfigurationPanel  matlab.ui.container.Panel
        Label_3                    matlab.ui.control.Label
        mmLabel_7                  matlab.ui.control.Label
        TiltAngleEditField         matlab.ui.control.NumericEditField
        TiltAngleEditFieldLabel    matlab.ui.control.Label
        TipDistanceEditField       matlab.ui.control.NumericEditField
        TipDistanceEditFieldLabel  matlab.ui.control.Label
        MRExUnitDropDown           matlab.ui.control.DropDown
        MRExUnitDropDownLabel      matlab.ui.control.Label
        SolverFidelitySlider       matlab.ui.control.Slider
        SolverFidelitySliderLabel  matlab.ui.control.Label
        ManipulatorRight           matlab.ui.control.DropDown
        ManipulatorLeft            matlab.ui.control.DropDown
        Image                      matlab.ui.control.Image
        ControlPanel               matlab.ui.container.Panel
        Label_5                    matlab.ui.control.Label
        ThetaPlusButton            matlab.ui.control.Button
        ThetaMinusButton           matlab.ui.control.Button
        ThetaTargetEditField       matlab.ui.control.NumericEditField
        Label_4                    matlab.ui.control.Label
        LabelTheta0                matlab.ui.control.Label
        Theta0Label                matlab.ui.control.Label
        ThetaLabel                 matlab.ui.control.Label
        LabelPhi0                  matlab.ui.control.Label
        LabelZ0                    matlab.ui.control.Label
        LabelY0                    matlab.ui.control.Label
        LabelX0                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        mmLabel_6                  matlab.ui.control.Label
        mmLabel_5                  matlab.ui.control.Label
        mmLabel_4                  matlab.ui.control.Label
        StatusLabel2               matlab.ui.control.Label
        Phi0Label                  matlab.ui.control.Label
        Z0Label                    matlab.ui.control.Label
        Y0Label                    matlab.ui.control.Label
        X0Label                    matlab.ui.control.Label
        GetPoseButton              matlab.ui.control.Button
        ExecuteButton              matlab.ui.control.Button
        PhiPlusButton              matlab.ui.control.Button
        PhiMinusButton             matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        PhiLabel                   matlab.ui.control.Label
        mmLabel_3                  matlab.ui.control.Label
        ZPlusButton                matlab.ui.control.Button
        ZMinusButton               matlab.ui.control.Button
        ZLabel                     matlab.ui.control.Label
        YPlusButton                matlab.ui.control.Button
        YMinusButton               matlab.ui.control.Button
        YLabel                     matlab.ui.control.Label
        mmLabel_2                  matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
        mmLabel                    matlab.ui.control.Label
        StatusLabel                matlab.ui.control.Label
        SendButton                 matlab.ui.control.Button
        XPlusButton                matlab.ui.control.Button
        PlanButton                 matlab.ui.control.Button
        XMinusButton               matlab.ui.control.Button
        PhiTargetEditField         matlab.ui.control.NumericEditField
        ZTargetEditField           matlab.ui.control.NumericEditField
        YTargetEditField           matlab.ui.control.NumericEditField
        XTargetEditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        XTarget = 0                    % Target X position [mm]
        YTarget = 0                    % Target Y position [mm]
        ZTarget = 0                    % Target Z position [mm]
        PhiTarget = 0                  % Target Phi angle [degrees]
        ThetaTarget = -45              % Target Theta angle [degrees]

        pathPlannerClient              % TCP Client instance
        
        trajectoryTime                 % Time vector for trajectory
        trajectoryData                 % Joint trajectory data (Nx6 matrix)

        baseModel = 'model_3T1R_Roll'
        
        manipulatorID1 = 'MC1';
        manipulatorID2 = 'MC2';

        currentState = 'idle'  % Track current operational state

        configMgr
    end
    
    methods (Access = private)
        
        function updateUIState(app, newState)
            % Update UI and buttons
            app.currentState = newState;
            
            switch newState
                case 'Idle'
                    app.ConnectButton.Enable = 'on';
                    app.DisconnectButton.Enable = 'off';
                    app.GetPoseButton.Enable = 'off';
                    app.PlanButton.Enable = 'off';
                    app.SendButton.Enable = 'off';
                    app.ExecuteButton.Enable = 'off';
                    app.ExportPathButton.Enable = 'off';
                    app.StatusLamp.Color = [0.5 0.5 0.5]; % Gray

                case 'Disconnected'
                    app.ConnectButton.Enable = 'on';
                    app.DisconnectButton.Enable = 'off';
                    app.PlanButton.Enable = 'off';
                    app.SendButton.Enable = 'off';
                    app.ExecuteButton.Enable = 'off';
                    app.StatusLamp.Color = [1 0 0]; % Red
                    
                case 'Connected'
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'on';
                    app.GetPoseButton.Enable = 'on';
                    app.PlanButton.Enable = 'on';
                    app.SendButton.Enable = 'off';
                    app.ExecuteButton.Enable = 'off';
                    app.StatusLamp.Color = [0 1 0]; % Green
                    
                case 'TrajectoryPlanned'
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'on';
                    app.GetPoseButton.Enable = 'on';
                    app.PlanButton.Enable = 'on';       % Allow for re-planning
                    app.SendButton.Enable = 'on';
                    app.ExecuteButton.Enable = 'off';   % Must send before execute
                    app.ExportPathButton.Enable = 'on';
                    
                case 'TrajectorySent'
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'on';
                    app.GetPoseButton.Enable = 'on';
                    app.PlanButton.Enable = 'on';
                    app.SendButton.Enable = 'off';      % once sent, cannot resend
                    app.ExecuteButton.Enable = 'on';
                    
                case 'Executing'
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'off';% Disconnect not allowed during executing path
                    app.GetPoseButton.Enable = 'on';
                    app.PlanButton.Enable = 'off';
                    app.SendButton.Enable = 'off';
                    app.ExecuteButton.Enable = 'off';
                    
                case 'ExecutionComplete'
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'on';
                    app.GetPoseButton.Enable = 'on';
                    app.PlanButton.Enable = 'on';
                    app.SendButton.Enable = 'off';      % Disable since trajectory was consumed
                    app.ExecuteButton.Enable = 'off';   % % Disable since trajectory was consumed
            end
            
            app.logMessage(sprintf('UI State changed to: %s', newState));
        end

        % Event handlers
        function onStatusUpdate(app, ~, eventData)
            app.logMessage(eventData.Message);
        end
        
        % Event handler when trajectory is ready for execution
        function onTrajectoryReady(app, ~, eventData)
            app.updateUIState('TrajectoryPlanned');
            app.StatusLabel.Text = 'Trajectory planned successfully';

            % Update trajectory plots if data is available
            if isprop(eventData, 'Data') && ~isempty(eventData.Data)
                app.updateTrajectoryPlots(eventData.Data);
            end
        end
        
        % Event handler when trajectory data is received by the controller
        function onPathDataReceived(app, ~, eventData)
            app.updateUIState('TrajectorySent');
            app.StatusLabel.Text = 'Trajectory sent, ready for execution';
            app.logMessage(eventData.Message);
        end

        function onPathExecutionStarted(app, ~, eventData)
            % Handle path execution started event
            app.updateUIState('Executing');
            app.StatusLabel.Text = 'Executing trajectory...';
       
            app.logMessage(eventData.Message);
        end

        % Handle successful trajectory execution
        function onTrajectoryExecuted(app, ~, eventData)
            app.updateUIState('ExecutionComplete');
            app.StatusLabel.Text = 'Trajectory execution completed successfully!';
            
            app.logMessage(eventData.Message);
            app.logMessage('Ready for next operation');
        end

        % Handle path execution failure
        function onPathExecutionFailed(app, ~, eventData)
            app.updateUIState('TrajectorySent');
            app.StatusLabel.Text = 'Path execution failed';
            app.logMessage(eventData.Message);
            
            % Show alert to user
            uialert(app.UIFigure, eventData.Message, 'Path Execution Failed');
        end

        % Event handler for connection state changes
        function onConnectionStateChanged(app, ~, ~)
            if app.pathPlannerClient.isConnected
                app.updateUIState('Connected');
            else
                app.updateUIState('Disconnected');
            end
        end

        % Helper function to log messages with timestamp
        function logMessage(app, message)
            timestamp = datestr(now, 'HH:MM:SS.FFF');
            logEntry = sprintf('[%s] %s', timestamp, message);
            
            % Handle cell array format for TextArea
            currentText = app.CommunicationLogTextArea.Value;
            if iscell(currentText)
                if isempty(currentText)
                    app.CommunicationLogTextArea.Value = {logEntry};
                else
                    app.CommunicationLogTextArea.Value = [currentText; {logEntry}];
                end
            else
                if isempty(currentText)
                    app.CommunicationLogTextArea.Value = {logEntry};
                else
                    % Convert string to cell array
                    app.CommunicationLogTextArea.Value = {currentText; logEntry};
                end
            end
            
            % Limit log size to prevent memory issue
            if iscell(app.CommunicationLogTextArea.Value)
                if length(app.CommunicationLogTextArea.Value) > 1000
                    app.CommunicationLogTextArea.Value = app.CommunicationLogTextArea.Value(end-800:end);
                end
            end
        end

        % Update trajectory plots with new data
        function updateTrajectoryPlots(app, trajectoryData)
            if isempty(trajectoryData) || ~isstruct(trajectoryData)
                return;
            end
            onPlotTrajectory(app, trajectoryData);
        end
        
        % Close request function
        function UIFigureCloseRequest(app, ~)
            % Ensure proper cleanup when closing the app
            if ~isempty(app.pathPlannerClient) && app.pathPlannerClient.isConnected
                app.pathPlannerClient.disconnect();
            end

            % Close Simulink models
            bdclose('all');
            
            % Delete the app
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.XTargetEditField.Value = app.XTarget;
            app.YTargetEditField.Value = app.YTarget;
            app.ZTargetEditField.Value = app.ZTarget;
            app.PhiTargetEditField.Value = app.PhiTarget;
            app.ThetaTargetEditField.Value = app.ThetaTarget;

            % Initialize TCP client
            app.pathPlannerClient = PathPlannerClient();

            app.configMgr = ConfigManager();

            % Add listenser for events
            addlistener(app.pathPlannerClient, 'StatusUpdate', @app.onStatusUpdate);
            addlistener(app.pathPlannerClient, 'TrajectoryReady', @app.onTrajectoryReady);
            addlistener(app.pathPlannerClient, 'PathDataReceived', @app.onPathDataReceived);
            addlistener(app.pathPlannerClient, 'TrajectoryExecuted', @app.onTrajectoryExecuted);
            addlistener(app.pathPlannerClient, 'ConnectionStateChanged', @app.onConnectionStateChanged);
            addlistener(app.pathPlannerClient, 'PathExecutionStarted', @app.onPathExecutionStarted);
            addlistener(app.pathPlannerClient, 'PathExecutionFailed', @app.onPathExecutionFailed);
            
            % Set initial states
            app.updateUIState('Idle');
            
            % Log startup
            app.logMessage('PathPlanner App started. Ready to connect to Controller Server.');
        end

        % Button pushed function: PlanButton
        function PlanButtonPushed(app, event)
            % Check connection status
            if isempty(app.pathPlannerClient) || ~app.pathPlannerClient.isConnected
                uialert(app.UIFigure, 'Please connect to Controller Server first', 'Not Connected');
                return;
            end
            
            try
                app.PlanButton.Enable = 'off';
                app.StatusLabel.Text = 'Planning trajectory...';
                drawnow;

                % Set IK/FK model from the dropdown menu
                app.baseModel = app.MRExUnitDropDown.Value;
                newModelFKName = string(app.baseModel) + "_FK";
                newModelIKName = string(app.baseModel) + "_IK";

                configObj = app.pathPlannerClient.getConfig();
                configObj.updateParameter('simulation', 'modelFK', newModelFKName);
                configObj.updateParameter('simulation', 'modelIK', newModelIKName);

                % Update target pose in client config (for onCalcIK to read)
                app.pathPlannerClient.setTargetPose( ...
                    app.XTargetEditField.Value, ...
                    app.YTargetEditField.Value, ...
                    app.ZTargetEditField.Value, ...
                    app.PhiTargetEditField.Value, ...
                    app.ThetaTargetEditField.Value);
                
                % Plan path (calculates trajectory but doesn't send to controller yet)
                success = app.pathPlannerClient.PlanPath();
                
                if success
                    app.StatusLabel.Text = 'Trajectory planned successfully';
                else
                    app.StatusLabel.Text = 'Failed to plan trajectory';
                end
                
            catch ME
                app.StatusLabel.Text = 'Error planning trajectory';
                uialert(app.UIFigure, ME.message, 'Plan Path Error');
            end
            
            app.PlanButton.Enable = 'on';
        end

        % Button pushed function: XPlusButton
        function XPlusButtonPushed(app, event)
            currentValue = app.XTargetEditField.Value;
            newValue = currentValue + 1;

            if newValue <= app.XTargetEditField.Limits(2)
                app.XTarget = newValue;
                app.XTargetEditField.Value = newValue;
            end
        end

        % Button pushed function: XMinusButton
        function XMinusButtonPushed(app, event)
            currentValue = app.XTargetEditField.Value;
            newValue = currentValue - 1;

            if newValue >= app.XTargetEditField.Limits(1)
                app.XTarget = newValue;
                app.XTargetEditField.Value = newValue;
            end
        end

        % Button pushed function: YPlusButton
        function YPlusButtonPushed(app, event)
            currentValue = app.YTargetEditField.Value;
            newValue = currentValue + 1;

            if newValue <= app.YTargetEditField.Limits(2)
                app.YTarget = newValue;
                app.YTargetEditField.Value = newValue;
            end
        end

        % Button pushed function: YMinusButton
        function YMinusButtonPushed(app, event)
            currentValue = app.YTargetEditField.Value;
            newValue = currentValue - 1;

            if newValue >= app.YTargetEditField.Limits(1)
                app.YTarget = newValue;
                app.YTargetEditField.Value = newValue;
            end
        end

        % Button pushed function: ZPlusButton
        function ZPlusButtonPushed(app, event)
            currentValue = app.ZTargetEditField.Value;
            newValue = currentValue + 1;

            if newValue <= app.ZTargetEditField.Limits(2)
                app.ZTarget = newValue;
                app.ZTargetEditField.Value = newValue;
            end
        end

        % Button pushed function: ZMinusButton
        function ZMinusButtonPushed(app, event)
            currentValue = app.ZTargetEditField.Value;
            newValue = currentValue - 1;

            if newValue >= app.ZTargetEditField.Limits(1)
                app.ZTarget = newValue;
                app.ZTargetEditField.Value = newValue;
            end
        end

        % Button pushed function: PhiPlusButton
        function PhiPlusButtonPushed(app, event)
            currentValue = app.PhiTargetEditField.Value;
            newValue = currentValue + 1;

            if newValue <= app.PhiTargetEditField.Limits(2)
                app.PhiTarget = newValue;
                app.PhiTargetEditField.Value = newValue;
            end
        end

        % Button pushed function: PhiMinusButton
        function PhiMinusButtonPushed(app, event)
            currentValue = app.PhiTargetEditField.Value;
            newValue = currentValue - 1;

            if newValue >= app.PhiTargetEditField.Limits(1)
                app.PhiTarget = newValue;
                app.PhiTargetEditField.Value = newValue;
            end
        end

        % Button pushed function: GetPoseButton
        function GetPoseButtonPushed(app, event)
            % Check if connected to controller
            if ~isempty(app.pathPlannerClient) && app.pathPlannerClient.isConnected
                try
                    app.GetPoseButton.Enable = 'off';
                    app.StatusLabel2.Text = 'Getting current pose...';
                    drawnow;
                    
                    % Set FK model from the dropdown menu
                    app.baseModel = app.MRExUnitDropDown.Value;
                    newModelFKName = string(app.baseModel) + "_FK";
    
                    configObj = app.pathPlannerClient.getConfig();
                    configObj.updateParameter('simulation', 'modelFK', newModelFKName);
                    
                    % Obtain and designate manipulator IDs
                    app.manipulatorID1 = app.ManipulatorLeft.Value;
                    app.manipulatorID2 = app.ManipulatorRight.Value;
                    configObj.updateParameter('controller', 'manipulatorID1', app.manipulatorID1);
                    configObj.updateParameter('controller', 'manipulatorID2', app.manipulatorID2);

                    % Call FK calculation through client config
                    [~, elapsedTime] = app.pathPlannerClient.GetPose();
                    
                    % Update UI with calculated pose (already in m from onCalcFK)
                    app.LabelX0.Text = sprintf('%.2f', app.pathPlannerClient.X0 * 1e3);
                    app.LabelY0.Text = sprintf('%.2f', app.pathPlannerClient.Y0 * 1e3);
                    app.LabelZ0.Text = sprintf('%.2f', app.pathPlannerClient.Z0 * 1e3);
                    app.LabelPhi0.Text = sprintf('%.2f', app.pathPlannerClient.Phi0);
                    
                    app.StatusLabel2.Text = sprintf('Pose updated in %.2f s', elapsedTime);
                    
                catch ME
                    app.StatusLabel2.Text = 'Error getting pose';
                    uialert(app.UIFigure, ME.message, 'Get Pose Error');
                end
                
                app.GetPoseButton.Enable = 'on';
            else
                % Not connected - show alert
                uialert(app.UIFigure, 'Please connect to Controller Server first', 'Not Connected');
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.ConnectButton.Enable = 'off';
            app.logMessage('Connecting to Controller Server...');
            
            % Attempt connection
            success = app.pathPlannerClient.connect();
            
            if success
                app.DisconnectButton.Enable = 'on';
                app.StatusLamp.Color = [0 1 0]; % Green
                app.logMessage('Successfully connected to Controller Server');
            else
                app.ConnectButton.Enable = 'on';
                app.StatusLamp.Color = [1 0 0]; % Red
                app.logMessage('Failed to connect to Controller Server');
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.pathPlannerClient.disconnect();

            app.logMessage('Disconnected from Controller Server');
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            % Send planned trajectory to controller
            if isempty(app.pathPlannerClient) || ~app.pathPlannerClient.isConnected
                uialert(app.UIFigure, 'Please connect to Controller Server first', 'Not Connected');
                return;
            end
            
            try
                app.SendButton.Enable = 'off';
                app.StatusLabel.Text = 'Sending trajectory to controller...';
                
                % Send the planned path to controller
                success = app.pathPlannerClient.SendPath();
                
                if success
                    app.StatusLabel.Text = 'Trajectory sent successfully';
                else
                    app.StatusLabel.Text = 'Failed to send trajectory';
                    app.SendButton.Enable = 'on';
                    app.ExecuteButton.Enable = 'off';
                end
                
            catch ME
                app.StatusLabel.Text = 'Error sending trajectory';
                uialert(app.UIFigure, ME.message, 'Send Path Error');
                app.SendButton.Enable = 'on';
                app.ExecuteButton.Enable = 'off';
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            % Execute the trajectory on the controller
            if isempty(app.pathPlannerClient) || ~app.pathPlannerClient.isConnected
                uialert(app.UIFigure, 'Please connect to Controller Server first', 'Not Connected');
                return;
            end
            
            try
                app.ExecuteButton.Enable = 'off';
                app.StatusLabel.Text = 'Sending execution command...';
                
                % Execute the path on controller
                success = app.pathPlannerClient.ExecutePath();
                
                if ~success
                    % Command failed to send
                    app.StatusLabel.Text = 'Failed to send execution command';
                    app.ExecuteButton.Enable = 'on';
                end
                % If success, the events will handle UI updates
                
            catch ME
                app.StatusLabel.Text = 'Error executing trajectory';
                uialert(app.UIFigure, ME.message, 'Execute Path Error');
                app.ExecuteButton.Enable = 'on';
            end
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
            % Clear the communication log text area
            app.CommunicationLogTextArea.Value = {''};
        end

        % Button pushed function: ThetaPlusButton
        function ThetaPlusButtonPushed(app, event)
            currentValue = app.ThetaTargetEditField.Value;
            newValue = currentValue + 1;

            if newValue <= app.ThetaTargetEditField.Limits(2)
                app.ThetaTarget = newValue;
                app.ThetaTargetEditField.Value = newValue;
            end
        end

        % Button pushed function: ThetaMinusButton
        function ThetaMinusButtonPushed(app, event)
            currentValue = app.ThetaTargetEditField.Value;
            newValue = currentValue - 1;

            if newValue >= app.ThetaTargetEditField.Limits(1)
                app.ThetaTarget = newValue;
                app.ThetaTargetEditField.Value = newValue;
            end
        end

        % Button pushed function: ExportPathButton
        function ExportPathButtonPushed(app, event)
            % 1. Validation: Ensure trajectory data is available
            if isempty(app.pathPlannerClient) || ~app.pathPlannerClient.isTrajectoryReady()
                uialert(app.UIFigure, ...
                    'No trajectory data available. Please generate a path first.', ...
                    'Export Error');
                return;
            end
        
            % 2. Data Retrieval: Get trajectory from Client
            % Retrieve the last calculated trajectory structure
            trajStruct = app.pathPlannerClient.getLastTrajectoryData();
            
            % Extract Time and Position data (Original Unit: Meters)
            timeData = trajStruct.qTime;
            posData_Meters = trajStruct.qData; 
            
            % 3. Unit Conversion: Meters to Micrometers (um)
            % Convert position to microns to match Controller protocol standards
            posData_Microns = posData_Meters * 1e6;
            
            % Combine into a single matrix: [Time, X1, Y1, Z1, X2, Y2, Z2]
            exportMatrix = [timeData, posData_Microns];
            
            % Define CSV Headers for clarity
            varNames = {'Time', 'X1_um', 'Y1_um', 'Z1_um', 'X2_um', 'Y2_um', 'Z2_um'};
            
            % Create Table object for convenient CSV export
            exportTable = array2table(exportMatrix, 'VariableNames', varNames);
            
            % 4. File Path Configuration
            % Retrieve the target directory from the UI Edit Field
            targetDir = app.ExportLocationEditField.Value;
            
            % Fallback: Use default 'data' folder in project root if field is empty
            if isempty(targetDir)
                targetDir = fullfile(pwd, 'data');
            end
            
            % Directory Validation: Check if folder exists, create if missing
            if ~isfolder(targetDir)
                try
                    % Attempt to create the directory structure
                    mkdir(targetDir);
                catch ME
                    uialert(app.UIFigure, ...
                        ['Failed to create directory: ', targetDir], ...
                        'Directory Error');
                    return;
                end
            end
        
            % Generate a timestamped filename to prevent overwriting
            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
            defaultFileName = sprintf('Trajectory_%s.csv', timestamp);
            
            % Construct the full initial path for the dialog
            fullDefaultPath = fullfile(targetDir, defaultFileName);
            
            % Open File Selection Dialog (uiputfile)
            % This starts in the user-specified directory but allows final modification
            [file, path] = uiputfile({'*.csv', 'CSV Files (*.csv)'}, ...
                                     'Save Trajectory Data', ...
                                     fullDefaultPath);
            
            % Check if the user cancelled the dialog
            if isequal(file, 0)
                return;
            end
            
            % Construct the final absolute file path
            finalFilePath = fullfile(path, file);
            
            % 5. Data Export Execution
            try
                writetable(exportTable, finalFilePath);
                
                % Provide success feedback to the user
                uialert(app.UIFigure, ...
                    sprintf('Data successfully exported to:\n%s', finalFilePath), ...
                    'Export Successful');
                    
            catch ME
                % Handle write errors (e.g., file open in another program)
                uialert(app.UIFigure, ...
                    ['Export failed: ', ME.message], ...
                    'Write Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.ControlPanel = uipanel(app.Panel);
            app.ControlPanel.Title = 'Control Panel';
            app.XTargetEditField = uieditfield(app.ControlPanel, 'numeric');
            app.XTargetEditField.Limits = [-20 20];
            app.YTargetEditField = uieditfield(app.ControlPanel, 'numeric');
            app.YTargetEditField.Limits = [-20 20];
            app.ZTargetEditField = uieditfield(app.ControlPanel, 'numeric');
            app.ZTargetEditField.Limits = [-30 30];
            app.PhiTargetEditField = uieditfield(app.ControlPanel, 'numeric');
            app.PhiTargetEditField.Limits = [-45 45];
            app.XMinusButton = uibutton(app.ControlPanel, 'push');
            app.XMinusButton.ButtonPushedFcn = createCallbackFcn(app, @XMinusButtonPushed, true);
            app.XMinusButton.Text = '-1';
            app.PlanButton = uibutton(app.ControlPanel, 'push');
            app.PlanButton.ButtonPushedFcn = createCallbackFcn(app, @PlanButtonPushed, true);
            app.PlanButton.Text = 'Plan';
            app.XPlusButton = uibutton(app.ControlPanel, 'push');
            app.XPlusButton.ButtonPushedFcn = createCallbackFcn(app, @XPlusButtonPushed, true);
            app.XPlusButton.Text = '+1';
            app.SendButton = uibutton(app.ControlPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.StatusLabel = uilabel(app.ControlPanel);
            app.StatusLabel.Text = 'idle';
            app.mmLabel = uilabel(app.ControlPanel);
            app.mmLabel.Text = 'mm';
            app.XLabel = uilabel(app.ControlPanel);
            app.XLabel.Text = 'Xᴱ';
            app.mmLabel_2 = uilabel(app.ControlPanel);
            app.mmLabel_2.Text = 'mm';
            app.YLabel = uilabel(app.ControlPanel);
            app.YLabel.Text = 'Yᴱ';
            app.YMinusButton = uibutton(app.ControlPanel, 'push');
            app.YMinusButton.ButtonPushedFcn = createCallbackFcn(app, @YMinusButtonPushed, true);
            app.YMinusButton.Text = '-1';
            app.YPlusButton = uibutton(app.ControlPanel, 'push');
            app.YPlusButton.ButtonPushedFcn = createCallbackFcn(app, @YPlusButtonPushed, true);
            app.YPlusButton.Text = '+1';
            app.ZLabel = uilabel(app.ControlPanel);
            app.ZLabel.Text = 'Zᴱ';
            app.ZMinusButton = uibutton(app.ControlPanel, 'push');
            app.ZMinusButton.ButtonPushedFcn = createCallbackFcn(app, @ZMinusButtonPushed, true);
            app.ZMinusButton.Text = '-1';
            app.ZPlusButton = uibutton(app.ControlPanel, 'push');
            app.ZPlusButton.ButtonPushedFcn = createCallbackFcn(app, @ZPlusButtonPushed, true);
            app.ZPlusButton.Text = '+1';
            app.mmLabel_3 = uilabel(app.ControlPanel);
            app.mmLabel_3.Text = 'mm';
            app.PhiLabel = uilabel(app.ControlPanel);
            app.PhiLabel.Text = 'Φᴱ';
            app.Label = uilabel(app.ControlPanel);
            app.Label.Text = '°';
            app.PhiMinusButton = uibutton(app.ControlPanel, 'push');
            app.PhiMinusButton.ButtonPushedFcn = createCallbackFcn(app, @PhiMinusButtonPushed, true);
            app.PhiMinusButton.Text = '-1';
            app.PhiPlusButton = uibutton(app.ControlPanel, 'push');
            app.PhiPlusButton.ButtonPushedFcn = createCallbackFcn(app, @PhiPlusButtonPushed, true);
            app.PhiPlusButton.Text = '+1';
            app.ExecuteButton = uibutton(app.ControlPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.GetPoseButton = uibutton(app.ControlPanel, 'push');
            app.GetPoseButton.ButtonPushedFcn = createCallbackFcn(app, @GetPoseButtonPushed, true);
            app.GetPoseButton.Text = 'Get Pose';
            app.X0Label = uilabel(app.ControlPanel);
            app.X0Label.Text = 'X⁰';
            app.Y0Label = uilabel(app.ControlPanel);
            app.Y0Label.Text = 'Y⁰';
            app.Z0Label = uilabel(app.ControlPanel);
            app.Z0Label.Text = 'Z⁰';
            app.Phi0Label = uilabel(app.ControlPanel);
            app.Phi0Label.Text = 'Φ⁰';
            app.StatusLabel2 = uilabel(app.ControlPanel);
            app.StatusLabel2.Text = 'idle';
            app.mmLabel_4 = uilabel(app.ControlPanel);
            app.mmLabel_4.Text = 'mm';
            app.mmLabel_5 = uilabel(app.ControlPanel);
            app.mmLabel_5.Text = 'mm';
            app.mmLabel_6 = uilabel(app.ControlPanel);
            app.mmLabel_6.Text = 'mm';
            app.Label_2 = uilabel(app.ControlPanel);
            app.Label_2.Text = '°';
            app.LabelX0 = uilabel(app.ControlPanel);
            app.LabelX0.Text = '0.0';
            app.LabelY0 = uilabel(app.ControlPanel);
            app.LabelY0.Text = '0.0';
            app.LabelZ0 = uilabel(app.ControlPanel);
            app.LabelZ0.Text = '0.0';
            app.LabelPhi0 = uilabel(app.ControlPanel);
            app.LabelPhi0.Text = '0.0';
            app.ThetaLabel = uilabel(app.ControlPanel);
            app.ThetaLabel.Text = 'Θᴱ';
            app.Theta0Label = uilabel(app.ControlPanel);
            app.Theta0Label.Text = 'Θ⁰';
            app.LabelTheta0 = uilabel(app.ControlPanel);
            app.LabelTheta0.Text = '0.0';
            app.Label_4 = uilabel(app.ControlPanel);
            app.Label_4.Text = '°';
            app.ThetaTargetEditField = uieditfield(app.ControlPanel, 'numeric');
            app.ThetaTargetEditField.Limits = [-90 0];
            app.ThetaTargetEditField.Value = -45;
            app.ThetaMinusButton = uibutton(app.ControlPanel, 'push');
            app.ThetaMinusButton.ButtonPushedFcn = createCallbackFcn(app, @ThetaMinusButtonPushed, true);
            app.ThetaMinusButton.Text = '-1';
            app.ThetaPlusButton = uibutton(app.ControlPanel, 'push');
            app.ThetaPlusButton.ButtonPushedFcn = createCallbackFcn(app, @ThetaPlusButtonPushed, true);
            app.ThetaPlusButton.Text = '+1';
            app.Label_5 = uilabel(app.ControlPanel);
            app.Label_5.Text = '°';
            app.MRExHYPERCUBEConfigurationPanel = uipanel(app.Panel);
            app.MRExHYPERCUBEConfigurationPanel.Title = 'M-REx HYPERCUBE Configuration';
            app.Image = uiimage(app.MRExHYPERCUBEConfigurationPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'figure_2R_RCM.png');
            app.ManipulatorLeft = uidropdown(app.MRExHYPERCUBEConfigurationPanel);
            app.ManipulatorLeft.Items = {'MC1', 'MC2', 'MC3', 'MC4'};
            app.ManipulatorLeft.Value = 'MC1';
            app.ManipulatorRight = uidropdown(app.MRExHYPERCUBEConfigurationPanel);
            app.ManipulatorRight.Items = {'MC1', 'MC2', 'MC3', 'MC4'};
            app.ManipulatorRight.Value = 'MC2';
            app.SolverFidelitySliderLabel = uilabel(app.MRExHYPERCUBEConfigurationPanel);
            app.SolverFidelitySliderLabel.Text = 'Solver Fidelity';
            app.SolverFidelitySlider = uislider(app.MRExHYPERCUBEConfigurationPanel);
            app.SolverFidelitySlider.Limits = [10 100];
            app.SolverFidelitySlider.MajorTicks = [10 20 30 40 50 60 70 80 90 100];
            app.SolverFidelitySlider.MajorTickLabels = {''};
            app.SolverFidelitySlider.MinorTicks = [10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.SolverFidelitySlider.Step = 10;
            app.SolverFidelitySlider.Value = 10;
            app.MRExUnitDropDownLabel = uilabel(app.MRExHYPERCUBEConfigurationPanel);
            app.MRExUnitDropDownLabel.Text = 'M-REx Unit';
            app.MRExUnitDropDown = uidropdown(app.MRExHYPERCUBEConfigurationPanel);
            app.MRExUnitDropDown.Items = {'model_3T1R_Roll', 'model_3T2R_Pitch_Roll', 'model_3T2R_Roll_Yaw', 'model_3T3R_Yaw_Pitch_Roll'};
            app.MRExUnitDropDown.Value = 'model_3T1R_Roll';
            app.TipDistanceEditFieldLabel = uilabel(app.MRExHYPERCUBEConfigurationPanel);
            app.TipDistanceEditFieldLabel.Text = 'Tip Distance';
            app.TipDistanceEditField = uieditfield(app.MRExHYPERCUBEConfigurationPanel, 'numeric');
            app.TipDistanceEditField.Value = 15;
            app.TiltAngleEditFieldLabel = uilabel(app.MRExHYPERCUBEConfigurationPanel);
            app.TiltAngleEditFieldLabel.Text = 'Tilt Angle';
            app.TiltAngleEditField = uieditfield(app.MRExHYPERCUBEConfigurationPanel, 'numeric');
            app.TiltAngleEditField.Value = 45;
            app.mmLabel_7 = uilabel(app.MRExHYPERCUBEConfigurationPanel);
            app.mmLabel_7.Text = 'mm';
            app.Label_3 = uilabel(app.MRExHYPERCUBEConfigurationPanel);
            app.Label_3.Text = '°';
            app.Panel_2 = uipanel(app.UIFigure);
            app.TrajectoryScope = uipanel(app.Panel_2);
            app.TrajectoryScope.Title = 'Generated Path';
            app.UIAxes2 = uiaxes(app.TrajectoryScope);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes1 = uiaxes(app.TrajectoryScope);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.ServerStatusPanel = uipanel(app.UIFigure);
            app.ServerStatusPanel.Title = 'Server Status';
            app.ConnectButton = uibutton(app.ServerStatusPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ServerStatusPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.StatusLampLabel = uilabel(app.ServerStatusPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ServerStatusPanel);
            app.CommunicationLogLabel = uilabel(app.ServerStatusPanel);
            app.CommunicationLogLabel.Text = 'Communication Log';
            app.CommunicationLogTextArea = uitextarea(app.ServerStatusPanel);
            app.ClearLogButton = uibutton(app.ServerStatusPanel, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.ExportPathButton = uibutton(app.ServerStatusPanel, 'push');
            app.ExportPathButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPathButtonPushed, true);
            app.ExportPathButton.Text = 'Export Path';
            app.Label_6 = uilabel(app.ServerStatusPanel);
            app.Label_6.Text = 'File Location';
            app.ExportLocationEditField = uieditfield(app.ServerStatusPanel, 'text');
            app.ExportLocationEditField.Value = 'data/';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PathPlanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end